classdef CNUPropeller < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SaveImage                       matlab.ui.control.Image
        OpenImage                       matlab.ui.control.Image
        TabGroup                        matlab.ui.container.TabGroup
        AirfoilDesignTab                matlab.ui.container.Tab
        Panel_1                         matlab.ui.container.Panel
        CreateAirfoilLabel              matlab.ui.control.Label
        ImportAirfoilButton             matlab.ui.control.Button
        GenerateNACAAirfoilButton       matlab.ui.control.Button
        DisplayModeLabel                matlab.ui.control.Label
        LineCheckBox                    matlab.ui.control.CheckBox
        NodesCheckBox                   matlab.ui.control.CheckBox
        ManageAirfoilLabel              matlab.ui.control.Label
        ExportSelectedAirfoilButton     matlab.ui.control.Button
        DeleteSelectedAirfoilButton     matlab.ui.control.Button
        Panel_1_2                       matlab.ui.container.Panel
        XButton                         matlab.ui.control.Button
        NACADigitsEditField             matlab.ui.control.EditField
        NumberofnodesEditField          matlab.ui.control.NumericEditField
        Numberofpoints151Label          matlab.ui.control.Label
        digitsLabel                     matlab.ui.control.Label
        CloseTEButtonGroup              matlab.ui.container.ButtonGroup
        YesButton                       matlab.ui.control.RadioButton
        NoButton                        matlab.ui.control.RadioButton
        NodesSpacingMethodButtonGroup   matlab.ui.container.ButtonGroup
        HalfCosineButton                matlab.ui.control.RadioButton
        UniformSpacingButton            matlab.ui.control.RadioButton
        GenerateButton                  matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        UIAxes1                         matlab.ui.control.UIAxes
        XfoilAnalysisTab                matlab.ui.container.Tab
        Panel_2                         matlab.ui.container.Panel
        SelectAirfoilLabel              matlab.ui.control.Label
        AirfoilDropDown_2               matlab.ui.control.DropDown
        DefineAnalysisLabel             matlab.ui.control.Label
        ReynoldsNumberEditField         matlab.ui.control.NumericEditField
        MachEditField                   matlab.ui.control.NumericEditField
        NCritEditField                  matlab.ui.control.NumericEditField
        TripLocationtopEditField        matlab.ui.control.NumericEditField
        TripLocationbotEditField        matlab.ui.control.NumericEditField
        IterationLimitEditField         matlab.ui.control.NumericEditField
        IterationLimitEditFieldLabel    matlab.ui.control.Label
        AnalysisSettingsLabel           matlab.ui.control.Label
        SequenceCheckBox                matlab.ui.control.CheckBox
        deltaEditField_3Label           matlab.ui.control.Label
        EndAlphaLabel                   matlab.ui.control.Label
        StartAlphaEditField             matlab.ui.control.NumericEditField
        EndAlphaEditField               matlab.ui.control.NumericEditField
        deltaEditField                  matlab.ui.control.NumericEditField
        ViscousCheckBox                 matlab.ui.control.CheckBox
        AnalyzeAirfoilButton            matlab.ui.control.Button
        ExportDataButton                matlab.ui.control.Button
        StartAlphaLabel                 matlab.ui.control.Label
        TripLocationbotEditFieldLabel   matlab.ui.control.Label
        TripLocationtopEditFieldLabel   matlab.ui.control.Label
        NCritEditFieldLabel             matlab.ui.control.Label
        MachEditFieldLabel              matlab.ui.control.Label
        ReynoldsNumberEditFieldLabel    matlab.ui.control.Label
        AirfoilDropDownLabel            matlab.ui.control.Label
        UIAxes2_1                       matlab.ui.control.UIAxes
        UIAxes2_2                       matlab.ui.control.UIAxes
        UIAxes2_3                       matlab.ui.control.UIAxes
        UIAxes2_4                       matlab.ui.control.UIAxes
        PolarExtrapolationTab           matlab.ui.container.Tab
        Panel_3                         matlab.ui.container.Panel
        SelectAirfoilLabel_2            matlab.ui.control.Label
        AirfoilDropDown_3               matlab.ui.control.DropDown
        AirfoilDropDown_2Label          matlab.ui.control.Label
        Create360PolarLabel             matlab.ui.control.Label
        ApSlider                        matlab.ui.control.Slider
        BpSlider                        matlab.ui.control.Slider
        AmSlider                        matlab.ui.control.Slider
        BmSlider                        matlab.ui.control.Slider
        SaveButton                      matlab.ui.control.Button
        BSlider_2Label                  matlab.ui.control.Label
        ASlider_2Label                  matlab.ui.control.Label
        BSliderLabel                    matlab.ui.control.Label
        ASliderLabel                    matlab.ui.control.Label
        UIAxes3_1                       matlab.ui.control.UIAxes
        UIAxes3_2                       matlab.ui.control.UIAxes
        PropellerDesignTab              matlab.ui.container.Tab
        ViewingControlLabel             matlab.ui.control.Label
        xzaxisButton                    matlab.ui.control.Button
        xyaxisButton                    matlab.ui.control.Button
        yzaxisButton                    matlab.ui.control.Button
        isometric1Button                matlab.ui.control.Button
        isometric2Button                matlab.ui.control.Button
        Panel_4                         matlab.ui.container.Panel
        DisplayControlLabel             matlab.ui.control.Label
        CenteryaxisCheckBox             matlab.ui.control.CheckBox
        WireframeCheckBox               matlab.ui.control.CheckBox
        PropellerCheckBox               matlab.ui.control.CheckBox
        PerspectiveCheckBox             matlab.ui.control.CheckBox
        PropellerDesignLabel            matlab.ui.control.Label
        PosEditField                    matlab.ui.control.NumericEditField
        ChordEditField                  matlab.ui.control.NumericEditField
        TwistEditField                  matlab.ui.control.NumericEditField
        AirfoilDropDown_4               matlab.ui.control.DropDown
        InsertSectionButton             matlab.ui.control.Button
        UITable2                        matlab.ui.control.Table
        DeleteSectionButton             matlab.ui.control.Button
        PropellernameTextAreaLabel      matlab.ui.control.Label
        ChordmEditFieldLabel            matlab.ui.control.Label
        TwistLabel                      matlab.ui.control.Label
        PosmEditFieldLabel              matlab.ui.control.Label
        AirfoilDropDown_3Label          matlab.ui.control.Label
        HubRadiusEditField              matlab.ui.control.NumericEditField
        HubRadiusmEditFieldLabel        matlab.ui.control.Label
        BladeNumberEditField            matlab.ui.control.NumericEditField
        PropellernameTextArea           matlab.ui.control.TextArea
        SavePropellerButton             matlab.ui.control.Button
        BladeNumberEditFieldLabel       matlab.ui.control.Label
        UIAxes4                         matlab.ui.control.UIAxes
        BEMAnalysisTab                  matlab.ui.container.Tab
        Panel_5                         matlab.ui.container.Panel
        ButtonGroup                     matlab.ui.container.ButtonGroup
        VariablexaxisLabel              matlab.ui.control.Label
        VButton                         matlab.ui.control.RadioButton
        RPMButton                       matlab.ui.control.RadioButton
        PitchButton                     matlab.ui.control.RadioButton
        ElementNumberEditFieldLabel     matlab.ui.control.Label
        SelectPropellerLabel            matlab.ui.control.Label
        PropellerDropDown               matlab.ui.control.DropDown
        PropellerDataLabel              matlab.ui.control.Label
        Constant1EditField              matlab.ui.control.NumericEditField
        const1Label                     matlab.ui.control.Label
        Constant2EditField              matlab.ui.control.NumericEditField
        SimulationParametersLabel       matlab.ui.control.Label
        ElementNumberEditField          matlab.ui.control.NumericEditField
        PropellerDropDownLabel          matlab.ui.control.Label
        EpsilonEditField                matlab.ui.control.NumericEditField
        RelaxFactorEditField            matlab.ui.control.NumericEditField
        IterationLimitEditField_2       matlab.ui.control.NumericEditField
        RhoEditField                    matlab.ui.control.NumericEditField
        ViscosityEditField              matlab.ui.control.NumericEditField
        PrandtlLossCorrecrionCheckBox   matlab.ui.control.CheckBox
        AnalysisSettingsLabel_2         matlab.ui.control.Label
        StartVariableEditField          matlab.ui.control.NumericEditField
        EndVariableEditField            matlab.ui.control.NumericEditField
        deltaEditField_2                matlab.ui.control.NumericEditField
        deltaEditField_2Label           matlab.ui.control.Label
        EndVmsLabel                     matlab.ui.control.Label
        AnalyzePropellerButton          matlab.ui.control.Button
        ExportDataButton_2              matlab.ui.control.Button
        StartVmsLabel                   matlab.ui.control.Label
        RelaxFactorEditFieldLabel       matlab.ui.control.Label
        EpsilonEditFieldLabel           matlab.ui.control.Label
        IterationLimitEditField_2Label  matlab.ui.control.Label
        ViscosityEditFieldLabel         matlab.ui.control.Label
        RhoEditFieldLabel               matlab.ui.control.Label
        const2Label                     matlab.ui.control.Label
        UIAxes5_1                       matlab.ui.control.UIAxes
        UIAxes5_2                       matlab.ui.control.UIAxes
        UIAxes5_3                       matlab.ui.control.UIAxes
        UIAxes5_4                       matlab.ui.control.UIAxes
        Image                           matlab.ui.control.Image
    end

    
    properties (Access = private)

        Airfoil_Info = containers.Map;
        Propeller_Info = containers.Map;
        MsgboxSet;

        % Tab-3 Properties
        Ap = 20;
        Bp = 10;
        Am = 15;
        Bm = 5;

        alpha_extra = [-180:3:-45 -44:44 45:3:180];
        cl_extra;
        cd_extra;

    end

    methods (Access = public)
        %% [Tab1] Airfoil Plotting Function
        function t1_plot(app)
            
            cla(app.UIAxes1);
            if isempty(app.UITable.Data); return; end

            selection = app.UITable.Selection;
            boolline = app.LineCheckBox.Value;
            boolnode = app.NodesCheckBox.Value;
            airfoil_list = app.UITable.Data(:,1);

            if boolline && boolnode                                         % Draw line and nodes
                for i = 1:length(app.Airfoil_Info)
                    airfoil_dat = app.Airfoil_Info(airfoil_list{i}).dat;
                    if i == selection
                        plot(app.UIAxes1, airfoil_dat(:,1), airfoil_dat(:,2), '-o', 'LineWidth', 2, 'MarkerSize', 4)    % Highlight selected airfoil
                    else
                        plot(app.UIAxes1, airfoil_dat(:,1), airfoil_dat(:,2), '-o', 'MarkerSize', 4)
                    end
                end

            elseif boolline                                                 % Draw line
                for i = 1:length(app.Airfoil_Info)
                    airfoil_dat = app.Airfoil_Info(airfoil_list{i}).dat;
                    if i == selection
                        plot(app.UIAxes1, airfoil_dat(:,1), airfoil_dat(:,2), 'LineWidth', 2)
                    else
                        plot(app.UIAxes1, airfoil_dat(:,1), airfoil_dat(:,2))
                    end
                end
                
            elseif boolnode                                                 % Draw nodes
                for i = 1:length(app.Airfoil_Info)
                    airfoil_dat = app.Airfoil_Info(airfoil_list{i}).dat;
                    if i == selection
                        plot(app.UIAxes1, airfoil_dat(:,1), airfoil_dat(:,2), 'o', 'LineWidth', 2, 'MarkerSize', 4)
                    else
                        plot(app.UIAxes1, airfoil_dat(:,1), airfoil_dat(:,2), 'o', 'MarkerSize', 4)
                    end
                end

            else
                return;

            end

        end

        %% [Tab2] 2-D Data Graph Plotting Function
        function t2_plot(app)

            t2_legend = {};
            airfoil_list = app.UITable.Data(:,1);
            cla(app.UIAxes2_1); cla(app.UIAxes2_2); cla(app.UIAxes2_3); cla(app.UIAxes2_4);

            for i = 1:length(app.Airfoil_Info)

                if isfield(app.Airfoil_Info(airfoil_list{i}), 'xf_res')
                    cur_res = app.Airfoil_Info(airfoil_list{i}).xf_res;

                    if strcmp(app.AirfoilDropDown_2.Value, airfoil_list{i}) % Current airfoil data
                        plot(app.UIAxes2_1, cur_res.alpha, cur_res.cl, '-o', 'LineWidth', 2, 'MarkerSize', 4)
                        plot(app.UIAxes2_2, cur_res.alpha, cur_res.cd, '-o', 'LineWidth', 2, 'MarkerSize', 4)
                        plot(app.UIAxes2_3, cur_res.cd, cur_res.cl, '-o', 'LineWidth', 2, 'MarkerSize', 4)
                        plot(app.UIAxes2_4, cur_res.alpha, cur_res.cl./cur_res.cd, '-o', 'LineWidth', 2, 'MarkerSize', 4)
                    else                                                    % Other airfoil data
                        plot(app.UIAxes2_1, cur_res.alpha, cur_res.cl)
                        plot(app.UIAxes2_2, cur_res.alpha, cur_res.cd)
                        plot(app.UIAxes2_3, cur_res.cd, cur_res.cl)
                        plot(app.UIAxes2_4, cur_res.alpha, cur_res.cl./cur_res.cd)
                    end
                    t2_legend = [t2_legend; airfoil_list{i}];
                end

            end
            legend(app.UIAxes2_4, t2_legend, 'Location', 'southeast')

        end

        %% [Tab3] 360-degree Polar Extrapoltion Function
        function Extrapolation(app)                                         % [Tab3] extrapolation function
            
            cur_airfoil = app.Airfoil_Info(app.AirfoilDropDown_3.Value);
            
            alpha = cur_airfoil.xf_res.alpha;                               % Import xfoil analyzed data
            cl = cur_airfoil.xf_res.cl;
            cd = cur_airfoil.xf_res.cd;
            thickness = cur_airfoil.thickness;
            camber = cur_airfoil.camber;
            yc = cur_airfoil.yc;

            cla(app.UIAxes3_1);                                             % Clear tab-3 axes
            cla(app.UIAxes3_2);

            if max(alpha) <= 0 || min(alpha) >= 0
                msgbox('\fontsize{12} Both positive and negative parts should be analyzed.', 'Warning', 'warn', app.MsgboxSet)
                return;
            end
            
            [app.cl_extra, app.cd_extra] = ExtrapolationFn(alpha, cl, app.Ap, app.Bp, app.Am, app.Bm, thickness, camber, yc);   % Calculate polar extrapolation
            
            plot(app.UIAxes3_1, alpha, cl);                                 % Plot xfoil analyzed graph
            plot(app.UIAxes3_2, alpha, cd);

            plot(app.UIAxes3_1, app.alpha_extra, app.cl_extra);             % Plot polar extrapolation graph
            plot(app.UIAxes3_2, app.alpha_extra, app.cd_extra);

        end

        %% [Tab4] Propeller Wireframe Plotting Function
        function t4_plot(app)

            cla(app.UIAxes4);
            rows = size(app.UITable2.Data, 1);
            selection = app.UITable2.Selection;
            bladeN = app.BladeNumberEditField.Value;
            bladeA = 360/bladeN;

            if rows == 0; return; end
            
            if app.PerspectiveCheckBox.Value                                % Change projection method
                camproj(app.UIAxes4, 'perspective');
            else
                camproj(app.UIAxes4, 'orthographic');
            end

            for i = 1:rows                                                  % Draw section
                % Align 1/4 chode of airfoil relative to y-axis
                airfoil_dat = app.Airfoil_Info(app.UITable2.Data{i,4}).dat;
                y_pos = zeros(size(airfoil_dat,1), 1) + app.UITable2.Data{i,1} + app.HubRadiusEditField.Value;
                temp_dat = [airfoil_dat(:,1) - 0.25, y_pos, airfoil_dat(:,2)];

                % Rotate airfoil
                [temp_dat(:,1), temp_dat(:,3)] = RotDat(temp_dat(:,1), temp_dat(:,3), app.UITable2.Data{i,3});

                % Scale airfoil
                temp_dat(:,1) = temp_dat(:,1)*app.UITable2.Data{i,2};
                temp_dat(:,3) = temp_dat(:,3)*app.UITable2.Data{i,2};

                % Plot airfoil
                if i == selection
                    plot3(app.UIAxes4, temp_dat(:, 1), temp_dat(:, 2),temp_dat(:, 3), 'LineWidth', 2, Color='#0072BD')
                else
                    plot3(app.UIAxes4, temp_dat(:, 1), temp_dat(:, 2),temp_dat(:, 3), Color='#0072BD')
                end
                hold(app.UIAxes4, 'on');

                if app.PropellerCheckBox.Value
                    for j = 1:bladeN-1
                        [temp_dat(:,1), temp_dat(:,2)] = RotDat(temp_dat(:,1), temp_dat(:,2), bladeA);
                        if i == selection
                            plot3(app.UIAxes4, temp_dat(:, 1), temp_dat(:, 2),temp_dat(:, 3), 'LineWidth', 2, Color='#0072BD')
                        else
                            plot3(app.UIAxes4, temp_dat(:, 1), temp_dat(:, 2),temp_dat(:, 3), Color='#0072BD')
                        end
                    end
                end

                % Store node of wire
                div = round(linspace(1, size(temp_dat,1), 9));
                div(end) = [];
                wf_dat(8*i-7:8*i, :) = temp_dat(div,:);

            end

            if app.CenteryaxisCheckBox.Value                                % Draw y-axis vector
                plot(app.UIAxes4, 0, 0, "o", 'LineWidth', 2, Color='#9dd058')
                quiver(app.UIAxes4, 0, 0, 0, (app.UITable2.Data{end,1} + app.HubRadiusEditField.Value)*1.1, 'off', Color='#9dd058')
                text(app.UIAxes4, 0, 0, '  Center', 'FontSize', 12, 'Color', '#77ab30')
            end

            if rows < 2; return; end

            for n = 1:8                                                     % Draw wire
                wire = zeros(rows,3);

                for m = 1:rows
                    wire(m,:) = wf_dat(8*m-8+n,:);
                end

                if n == 1 || n == 5
                    plot3(app.UIAxes4, wire(:,1), wire(:,2), wire(:,3), Color='#0072BD')
                elseif app.WireframeCheckBox.Value
                    plot3(app.UIAxes4, wire(:,1), wire(:,2), wire(:,3), Color='#99C6E4')
                end

                if app.PropellerCheckBox.Value
                    for j = 1:bladeN-1
                        [wire(:,1), wire(:,2)] = RotDat(wire(:,1), wire(:,2), bladeA);
                        if n == 1 || n == 5
                            plot3(app.UIAxes4, wire(:,1), wire(:,2), wire(:,3), Color='#0072BD')
                        elseif app.WireframeCheckBox.Value
                            plot3(app.UIAxes4, wire(:,1), wire(:,2), wire(:,3), Color='#99C6E4')
                        end
                    end
                end
                
            end

        end

        %% [Tab5] BEM Calculation Data Plotting Function
        function t5_plot(app)

            cur_prop = app.Propeller_Info(app.PropellerDropDown.Value);
            cla(app.UIAxes5_1); cla(app.UIAxes5_2); cla(app.UIAxes5_3); cla(app.UIAxes5_4);
            if ~isfield(cur_prop, 'bem_res'); return; end

            if cur_prop.vari_mode == 1                                      % Plot BEM data for windspeed
                xlabel(app.UIAxes5_1, 'V (m/s)');
                xlabel(app.UIAxes5_2, 'V (m/s)');
                xlabel(app.UIAxes5_3, 'V (m/s)');
                xlabel(app.UIAxes5_4, 'V (m/s)');
                label_color = '#0063A6';
            elseif cur_prop.vari_mode == 2                                  % Plot BEM data for RPM
                xlabel(app.UIAxes5_1, 'RPM');
                xlabel(app.UIAxes5_2, 'RPM');
                xlabel(app.UIAxes5_3, 'RPM');
                xlabel(app.UIAxes5_4, 'RPM');
                label_color = '#A63200';
            elseif cur_prop.vari_mode == 3                                  % Plot BEM data for pitch
                xlabel(app.UIAxes5_1, 'Pitch (˚ )');
                xlabel(app.UIAxes5_2, 'Pitch (˚ )');
                xlabel(app.UIAxes5_3, 'Pitch (˚ )');
                xlabel(app.UIAxes5_4, 'Pitch (˚ )');
                label_color = '#488000';
            end
            
            if size(cur_prop.bem_res,1) == 1
                plot(app.UIAxes5_1, cur_prop.bem_res(:,1), cur_prop.bem_res(:,2), 'x', Color=label_color);
                plot(app.UIAxes5_2, cur_prop.bem_res(:,1), cur_prop.bem_res(:,3), 'x', Color=label_color);
                plot(app.UIAxes5_3, cur_prop.bem_res(:,1), cur_prop.bem_res(:,4), 'x', Color=label_color);
                plot(app.UIAxes5_4, cur_prop.bem_res(:,1), cur_prop.bem_res(:,5), 'x', Color=label_color);
            else
                plot(app.UIAxes5_1, cur_prop.bem_res(:,1), cur_prop.bem_res(:,2), Color=label_color);
                plot(app.UIAxes5_2, cur_prop.bem_res(:,1), cur_prop.bem_res(:,3), Color=label_color);
                plot(app.UIAxes5_3, cur_prop.bem_res(:,1), cur_prop.bem_res(:,4), Color=label_color);
                plot(app.UIAxes5_4, cur_prop.bem_res(:,1), cur_prop.bem_res(:,5), Color=label_color);
            end

        end

        %% [Tab5] Length of Data Equalizing Function
        function [alpha_data, cl1, cd1, cl2, cd2] = equalizer(app, i, Blade_airfoil)

            if strcmp(Blade_airfoil{i},Blade_airfoil{i+1})                  % Both airfoil in the block are same
                airfoil = app.Airfoil_Info(Blade_airfoil{i});

                alpha_data = airfoil.ext_res.alpha360;
                cl1 = airfoil.ext_res.cl360;
                cd1 = airfoil.ext_res.cd360;
                cl2 = cl1;
                cd2 = cd1;
            else                                                            % Both airfoil in the block are diffrent
                airfoil_1 = app.Airfoil_Info(Blade_airfoil{i});
                airfoil_2 = app.Airfoil_Info(Blade_airfoil{i+1});

                alpha_data1 = airfoil_1.ext_res.alpha360;
                alpha_data2 = airfoil_2.ext_res.alpha360;
                alpha_data = unique([alpha_data1; alpha_data2]);            % Union of alpha data

                cl1 = interp1(alpha_data1, airfoil_1.ext_res.cl360, alpha_data);
                cd1 = interp1(alpha_data1, airfoil_1.ext_res.cd360, alpha_data);
                cl2 = interp1(alpha_data2, airfoil_2.ext_res.cl360, alpha_data);
                cd2 = interp1(alpha_data2, airfoil_2.ext_res.cd360, alpha_data);
            end

        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            filePath = fileparts(mfilename('fullpath'));
            cd(filePath);                                                   % Change 'Current Folder' to opened app adress
            fprintf( 'Current Folder Changed -> %s\n' , filePath);

            app.MsgboxSet.Interpreter = 'tex';                              % Messagebox setting
            app.MsgboxSet.WindowStyle = 'modal';

            app.UITable.ColumnFormat = {[] 'shortG' 'shortG' 'shortG'};     % Change UITable format
            app.UITable2.ColumnFormat = {'shortG' 'shortG' 'shortG' 'shortG' []};
            
            hold(app.UIAxes1,'on');                                         % Hold on axes
            hold(app.UIAxes2_1, 'on'); hold(app.UIAxes2_2, 'on'); hold(app.UIAxes2_3, 'on'); hold(app.UIAxes2_4, 'on');
            hold(app.UIAxes3_1, 'on'); hold(app.UIAxes3_2, 'on');
            
            app.const1Label.Text = 'RPM';
            app.const2Label.Text = 'Pitch (˚ )';
            app.StartVmsLabel.Text = 'Start V (m/s)';
            app.EndVmsLabel.Text = 'End V (m/s)';
        end

        % Button pushed function: ImportAirfoilButton
        function ImportAirfoilButtonPushed(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);                                                    % Create a dummy figure so that uigetfile doesn't minimize our GUI (MATLAB 2022a version has bug)
            [filename, filepath] = uigetfile({'*.dat;*.txt', 'airfoil data file (*.dat,*.txt)'}, 'Select airfoil data file');   % Open file selection dialog box, display .dat and .txt file
            delete(f);                                                                                                          % Delete the dummy figure

            if filename == 0; return; end

            [~, name, ~] = fileparts(filename);
            temp_struct.dat = table2array(readtable([filepath filename]));  % Get imported file data

            if size(temp_struct.dat,2) ~= 2
                msgbox('\fontsize{12} Data in the file is not correct.', 'Warning', 'warn', app.MsgboxSet)  % Warning about import data is not correct
                return;
            elseif isKey(app.Airfoil_Info, name)
                msgbox('\fontsize{12} Airfoil with the same name cannot be import.', 'Warning', 'warn', app.MsgboxSet)  % Warning about airfoil of the same name
                return;
            end

            [temp_struct.camber, temp_struct.thickness, temp_struct.yc] = GetCamThickYc(temp_struct.dat);
            app.Airfoil_Info(name) = temp_struct;                           % Store airfoil nodes, camber, thickness, yc
            
            app.UITable.Data = [app.UITable.Data; {name} {round(temp_struct.thickness*100)} {round(temp_struct.camber*100)} {size(temp_struct.dat,1)}]; % Display data on table
            t1_plot(app);
        end

        % Button pushed function: GenerateNACAAirfoilButton
        function GenerateNACAAirfoilButtonPushed(app, event)
            app.NACADigitsEditField.Value = '';
            app.Panel_1_2.Visible = 'on';
            app.Panel_1_2.Enable = 'on';
        end

        % Button pushed function: XButton
        function XButtonPushed(app, event)
            app.Panel_1_2.Visible = 'off';
            app.Panel_1_2.Enable = 'off';
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            iaf.designation = app.NACADigitsEditField.Value;                % NACA airfoil's digits
            iaf.n = app.NumberofnodesEditField.Value;                       % Number of nodes
            
            if rem(iaf.n, 2) == 0
                iaf.n = iaf.n/2;
            else
                iaf.n = (iaf.n - 1)/2;
            end

            iaf.HalfCosineSpacing = app.HalfCosineButton.Value;             % Spacing check-box
            iaf.is_finiteTE = app.NoButton.Value;                           % Close or Open TE
            iaf.wantFile = 0;
            
            if ~isnan(str2double(iaf.designation)) && (length(iaf.designation) == 5 || length(iaf.designation) == 4)    % Check if length of digits is 4 or 5, and digits is numeric
                if length(iaf.designation) == 4                             % NACA 4 digit
                    af = naca4gen(iaf);
                else                                                        % NACA 5 digit
                    af = naca5gen(iaf);
                end
    
                if isKey(app.Airfoil_Info, af.name)
                    msgbox('\fontsize{12} Airfoil with the same name cannot be import.', 'Warning', 'warn', app.MsgboxSet)  % Warning about airfoil of the same name
                    return;
                end
    
                temp_struct.dat = [af.x af.z];
                [temp_struct.camber, temp_struct.thickness, temp_struct.yc] = GetCamThickYc(temp_struct.dat);
                app.Airfoil_Info(af.name) = temp_struct;                    % Store airfoil nodes, camber, thickness, yc
    
                app.UITable.Data = [app.UITable.Data; {af.name} {round(temp_struct.thickness*100)} {round(temp_struct.camber*100)} {size(temp_struct.dat,1)}];  % Display data on table
                t1_plot(app);
            else                                                            % Invalid input
                msgbox('\fontsize{12} Please enter correct digit.', 'Warning', 'warn', app.MsgboxSet)
                return;
            end
            
            app.Panel_1_2.Visible = 'off';
            app.Panel_1_2.Enable = 'off';
        end

        % Value changed function: NodesCheckBox
        function NodesCheckBoxValueChanged(app, event)
            t1_plot(app);
        end

        % Value changed function: LineCheckBox
        function LineCheckBoxValueChanged(app, event)
            t1_plot(app);
        end

        % Button pushed function: ExportSelectedAirfoilButton
        function ExportSelectedAirfoilButtonPushed(app, event)
            selection = app.UITable.Selection;
            if isempty(selection); return; end

            airfoil_list = app.UITable.Data(:,1);
            airfoil_dat = app.Airfoil_Info(airfoil_list{selection}).dat;
            
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);                                        % Create a dummy figure so that uigetfile doesn't minimize our GUI (MATLAB 2022a version has bug)
            [filename, filepath] = uiputfile('*.dat', 'Write down file name', [airfoil_list{selection} '.dat']);    % File export dialog box
            delete(f);                                                                                              % Delete the dummy figure
            if filename == 0; return; end

            [~, name, ext] = fileparts(filename);
            if ext == ".dat" || ext == ".txt"
                writefilename = [filepath filename];
            else
                writefilename = [filepath name '.dat'];
            end
            writematrix(round(airfoil_dat, 6), writefilename, 'Delimiter', ' ', 'QuoteStrings', 'none')
        end

        % Button pushed function: DeleteSelectedAirfoilButton
        function DeleteSelectedAirfoilButtonPushed(app, event)
            selection = app.UITable.Selection;
            if isempty(selection); return; end

            airfoil_list = app.UITable.Data(:,1);
            remove(app.Airfoil_Info, airfoil_list{selection});              % Delete selected airfoil data

            app.UITable.Data(selection,:) = [];                             % Delete selected table row
            t1_plot(app);
        end

        % Selection changed function: UITable
        function UITableSelectionChanged(app, event)
            t1_plot(app);
        end

        % Button down function: XfoilAnalysisTab
        function XfoilAnalysisTabButtonDown(app, event)
            if isempty(app.Airfoil_Info)                                    % If dosen't import or create any airfoil at tab-1.
                app.Panel_2.Enable = 'off';
                app.AirfoilDropDown_2.Items = {'* empty'};
                cla(app.UIAxes2_1); cla(app.UIAxes2_2); cla(app.UIAxes2_3); cla(app.UIAxes2_4);
                return;
            end

            app.Panel_2.Enable = 'on';
            app.AirfoilDropDown_2.Items = app.UITable.Data(:,1);            % Input airfoil names into tab-2 drop-down
            t2_plot(app);
        end

        % Value changed function: SequenceCheckBox
        function SequenceCheckBoxValueChanged(app, event)
            value = app.SequenceCheckBox.Value;
            if value
                app.EndAlphaEditField.Enable = "on";
                app.deltaEditField.Enable = "on";
            else
                app.EndAlphaEditField.Enable = "off";
                app.deltaEditField.Enable = "off";
            end
        end

        % Button pushed function: AnalyzeAirfoilButton
        function AnalyzeAirfoilButtonPushed(app, event)
            %% Enter Parameters
            iter = num2str(app.IterationLimitEditField.Value);              % Iteration no.
            Re = num2str(app.ReynoldsNumberEditField.Value);                % Reynolds no.
            Mach = num2str(app.MachEditField.Value);                        % Mach no.
            
            Ncr = num2str(app.NCritEditField.Value);                        % Ncrit
            XtrU = num2str(app.TripLocationtopEditField.Value);             % XTR top
            XtrB = num2str(app.TripLocationbotEditField.Value);             % XTR bottom
            
            is_seq = app.SequenceCheckBox.Value;                            % Alfa-sequence
            visc = app.ViscousCheckBox.Value;                               % viscous 
            %init_BL = false;                                               % Initialize BL
            
            st = num2str(app.StartAlphaEditField.Value);                    % Start alfa
            ed = num2str(app.EndAlphaEditField.Value);                      % End alfa
            dt = num2str(app.deltaEditField.Value);                         % Delta alfa

            airfoilFileName = 'tempairfoil.dat';                            % Airfoil data filename
            cmdFileName = 'xfoilcmd.txt';                                   % Command filename
            resultFileName  = 'tempres.txt';                                % Polar result filename

            %% Call xfoil & get result

            % Create temp. airfoil data file
            airfoil_name = app.AirfoilDropDown_2.Value;
            airfoil_dat = app.Airfoil_Info(airfoil_name).dat;
            writematrix(round(airfoil_dat, 6), airfoilFileName, 'Delimiter', ' ', 'QuoteStrings', 'none')

            % Create and open temp. command text file
            writematrix([], cmdFileName)
            cmdFile = fopen(cmdFileName,'w');

            % Delete polar files if they exist
            if exist(strcat(resultFileName),'file')
                delete(strcat(resultFileName));
            end

            % Load airfoil data file
            fprintf(cmdFile,['load ' airfoilFileName '\n']);
            fprintf(cmdFile,[airfoil_name '\n']);
            %fprintf(cmdFile,'pane\n');
            
            % Set XTR
            fprintf(cmdFile,'oper\n');
            fprintf(cmdFile,'vpar\n');
            fprintf(cmdFile,'XTR\n');
            fprintf(cmdFile,[ XtrU '\n']);
            fprintf(cmdFile,[ XtrB '\n']);
            
            % set Ncrit
            fprintf(cmdFile,'N\n');
            fprintf(cmdFile,[ Ncr '\n']);
            fprintf(cmdFile,'\n');
            
            % Oper mode
            fprintf(cmdFile,['iter ' iter '\n']);
            
            if visc
                % visc mode
                fprintf(cmdFile,'visc\n');
                fprintf(cmdFile,[Re '\n']);
            else
                fprintf(cmdFile,['re ' Re '\n']);
            end
            fprintf(cmdFile,['mach ' Mach '\n']);
            
            % seqp and polar
            fprintf(cmdFile,'seqp\n');
            fprintf(cmdFile,'pacc\n');
            fprintf(cmdFile,['./' resultFileName '\n\n']);
            if is_seq
                fprintf(cmdFile,['aseq ', st, ' ', ed,  ' ', dt, ' ', '\n']);
            else
                fprintf(cmdFile,['a ', st, '\n']);
            end
            
            % Close file
            fclose(cmdFile);
            
            cmd = ['xfoil.exe < ' cmdFileName];
            [status, result] = system(cmd);

            %% Rearrange and Plot xfoil Polar Result

            temp_info = app.Airfoil_Info(airfoil_name);
            res = readtable(resultFileName);                          % alpha, CL, CD, CDp, CM, Top_Xtr, Bot_Xtr data

            % Sort and overwrite polar result
            if isfield(temp_info, 'xf_inputs') && strcmp(temp_info.xf_inputs, [Re Mach Ncr XtrU XtrB])
                old_res = temp_info.xf_res.table;
                tmp_res = [res; old_res];
                [~, idx] = unique(tmp_res(:,1));
                res = tmp_res(idx,:);
            end

            % Store(Overwrite) polar result
            res_array = table2array(res);
            tmp.alpha   = res_array(:,1);
            tmp.cl      = res_array(:,2);
            tmp.cd      = res_array(:,3);
            tmp.re      = Re;
            tmp.table   = res;
            temp_info.xf_res = tmp;
            temp_info.xf_inputs = [Re Mach Ncr XtrU XtrB];

            app.Airfoil_Info(app.AirfoilDropDown_2.Value) = temp_info;

            t2_plot(app);

            % Delete temp. files
            delete(strcat(airfoilFileName));
            delete(strcat(cmdFileName));
            delete(strcat(resultFileName));
        end

        % Button pushed function: ExportDataButton
        function ExportDataButtonPushed(app, event)
            cur_airfoil = app.Airfoil_Info(app.AirfoilDropDown_2.Value);
            if ~isfield(cur_airfoil, 'xf_res'); return; end

            Re = cur_airfoil.xf_res.re;

            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);                                                    % Create a dummy figure so that uigetfile doesn't minimize our GUI (MATLAB 2022a version has bug)
            [filename, filepath] = uiputfile('*.txt', 'Write down file name', [app.AirfoilDropDown_2.Value '_Re' Re '.txt']);   % File export dialog box
            delete(f);                                                                                                          % Delete the dummy figure
            if filename == 0; return; end
            
            temp = ["Alpha    CL           CD           CDp          CM            TopXtr       BotXtr"; num2str(table2array(cur_airfoil.xf_res.table),6)];
            [~, name, ~] = fileparts(filename);

            writefilename = [filepath name '.txt'];
            writematrix(temp, writefilename, 'Delimiter', '\t', 'QuoteStrings', 'none')
        end

        % Value changed function: AirfoilDropDown_2
        function AirfoilDropDown_2ValueChanged(app, event)
            t2_plot(app);
        end

        % Button down function: PolarExtrapolationTab
        function PolarExtrapolationTabButtonDown(app, event)
            if isempty(app.Airfoil_Info)                                    % If dosen't import or create any airfoil at tab-1.
                app.Panel_3.Enable = 'off';
                app.AirfoilDropDown_3.Items = {'* empty'};
                cla(app.UIAxes3_1); cla(app.UIAxes3_2);
                return;
            end

            airfoil_list = app.UITable.Data(:,1);
            dropdown_list = {};

            for i = 1:length(app.Airfoil_Info)
                if isfield(app.Airfoil_Info(airfoil_list{i}), 'xf_inputs')  % Select only analyzed airfoil
                    dropdown_list = [dropdown_list airfoil_list{i}];        % Add airfoil's name to tab-3 drop-down list
                end
            end

            if isempty(dropdown_list)
                app.Panel_3.Enable = 'off';
                app.AirfoilDropDown_3.Items = {'* empty'};
                cla(app.UIAxes3_1); cla(app.UIAxes3_2);
            else
                app.Panel_3.Enable = 'on';
                app.AirfoilDropDown_3.Items = dropdown_list;                % Input list into tab-3 drop-down
                Extrapolation(app);
            end
        end

        % Value changed function: AirfoilDropDown_3
        function AirfoilDropDown_3ValueChanged(app, event)
            Extrapolation(app);
        end

        % Value changing function: ApSlider
        function ApSliderValueChanging(app, event)
            app.Ap = max(event.Value, 0.01);
            Extrapolation(app);
        end

        % Value changing function: BpSlider
        function BpSliderValueChanging(app, event)
            app.Bp = max(event.Value, 0.01);
            Extrapolation(app);
        end

        % Value changing function: AmSlider
        function AmSliderValueChanging(app, event)
            app.Am = max(event.Value, 0.01);
            Extrapolation(app);
        end

        % Value changing function: BmSlider
        function BmSliderValueChanging(app, event)
            app.Bm = max(event.Value, 0.01);
            Extrapolation(app);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            cur_airfoil = app.Airfoil_Info(app.AirfoilDropDown_3.Value);
            
            alpha = cur_airfoil.xf_res.alpha;
            cl = cur_airfoil.xf_res.cl;
            cd = cur_airfoil.xf_res.cd;

            [temp.alpha360, temp.cl360, temp.cd360] = ExtrapolationSave(alpha, cl, cd, app.cl_extra, app.cd_extra);

            cla(app.UIAxes3_1);                                             % Clear axes
            cla(app.UIAxes3_2);
            
            plot(app.UIAxes3_1, temp.alpha360, temp.cl360, 'Color', "#7E2F8E");
            plot(app.UIAxes3_2, temp.alpha360, temp.cd360, 'Color', "#7E2F8E");

            cur_airfoil.ext_res = temp;
            app.Airfoil_Info(app.AirfoilDropDown_3.Value) = cur_airfoil;    % Store polar extrapolation result
        end

        % Button down function: PropellerDesignTab
        function PropellerDesignTabButtonDown(app, event)
            if isempty(app.Airfoil_Info)                                    % If dosen't import or create any airfoil at tab-1.
                app.Panel_4.Enable = 'off';
                app.AirfoilDropDown_4.Items = {'* empty'};
                cla(app.UIAxes4);
                return;
            end

            airfoil_list = app.UITable.Data(:,1);
            dropdown_list = {};

            for i = 1:length(app.Airfoil_Info)
                if isfield(app.Airfoil_Info(airfoil_list{i}), 'ext_res')    % Select only extrapolated airfoil
                    dropdown_list = [dropdown_list airfoil_list{i}];        % Add airfoil's name to tab-4 drop-down list
                end
            end

            if isempty(dropdown_list)
                app.Panel_4.Enable = 'off';
                app.AirfoilDropDown_4.Items = {'* empty'};
                cla(app.UIAxes4);
            else
                app.Panel_4.Enable = 'on';
                app.AirfoilDropDown_4.Items = dropdown_list;                % Input list into tab-4 drop-down

                for i = size(app.UITable2.Data,1):-1:1                      % Delete rows with erased airfoiles in the table
                    if isempty(intersect(app.UITable2.Data(i,4), dropdown_list))
                        app.UITable2.Data(i,:) = [];
                    end
                end
                t4_plot(app);
            end
        end

        % Value changed function: WireframeCheckBox
        function WireframeCheckBoxValueChanged(app, event)
            t4_plot(app);
        end

        % Value changed function: PropellerCheckBox
        function PropellerCheckBoxValueChanged(app, event)
            t4_plot(app);
        end

        % Value changed function: CenteryaxisCheckBox
        function CenteryaxisCheckBoxValueChanged(app, event)
            t4_plot(app);
        end

        % Value changed function: PerspectiveCheckBox
        function PerspectiveCheckBoxValueChanged(app, event)
            t4_plot(app);
        end

        % Button pushed function: InsertSectionButton
        function InsertSectionButtonPushed(app, event)
            app.UITable2.Data = [{app.PosEditField.Value app.ChordEditField.Value app.TwistEditField.Value app.AirfoilDropDown_4.Value}; app.UITable2.Data];    % Store in table
            [~, idx] = unique(cell2mat(app.UITable2.Data(:,1)));            % Sort by position (if input same position, overwrite row)
            app.UITable2.Data = app.UITable2.Data(idx,:);

            t4_plot(app);
        end

        % Selection changed function: UITable2
        function UITable2SelectionChanged(app, event)
            t4_plot(app);
        end

        % Button pushed function: DeleteSectionButton
        function DeleteSectionButtonPushed(app, event)
            selection = app.UITable2.Selection;
            if isempty(selection); return; end
            
            rows = size(app.UITable2.Data,1);
            if selection == rows && rows > 1
                app.UITable2.Selection = size(app.UITable2.Data,1) - 1;
            end
            app.UITable2.Data(selection,:) = [];                            % Delete selected section
            
            t4_plot(app);
        end

        % Value changed function: HubRadiusEditField
        function HubRadiusEditFieldValueChanged(app, event)
            t4_plot(app);
        end

        % Value changed function: BladeNumberEditField
        function BladeNumberEditFieldValueChanged(app, event)
            t4_plot(app);
        end

        % Button pushed function: SavePropellerButton
        function SavePropellerButtonPushed(app, event)
            if size(app.UITable2.Data,1) < 2
                msgbox('\fontsize{12} The number of sections must be at least 2.', 'Warning', 'warn', app.MsgboxSet)
                return;
            end

            temp.table = app.UITable2.Data;
            temp.hub_radius = app.HubRadiusEditField.Value;
            temp.blade_number = app.BladeNumberEditField.Value;

            app.Propeller_Info(app.PropellernameTextArea.Value{1}) = temp;  % Store propeller specification
        end

        % Button pushed function: xyaxisButton
        function xyaxisButtonPushed(app, event)
            view(app.UIAxes4,[0 0 1]);
        end

        % Button pushed function: yzaxisButton
        function yzaxisButtonPushed(app, event)
            view(app.UIAxes4,[1 0 0]);
        end

        % Button pushed function: xzaxisButton
        function xzaxisButtonPushed(app, event)
            view(app.UIAxes4,[0 1 0]);
        end

        % Button pushed function: isometric1Button
        function isometric1ButtonPushed(app, event)
            view(app.UIAxes4,[-1 -1 1]);
        end

        % Button pushed function: isometric2Button
        function isometric2ButtonPushed(app, event)
            view(app.UIAxes4,[1 1 1]);
        end

        % Button down function: BEMAnalysisTab
        function BEMAnalysisTabButtonDown(app, event)
            if isempty(app.Propeller_Info)                                  % If dosen't save any propeller at tab-4.
                app.Panel_5.Enable = 'off';
                app.PropellerDropDown.Items = {'* empty'};
                cla(app.UIAxes2_1); cla(app.UIAxes2_2); cla(app.UIAxes2_3); cla(app.UIAxes2_4);
                return;
            end
            
            app.Panel_5.Enable = 'on';
            app.PropellerDropDown.Items = keys(app.Propeller_Info);         % Input propeller names into tab-5 drop-down
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            if app.VButton.Value                                            % Variable = V (windspeed)

                % RPM
                app.const1Label.FontColor = [0 0.3882 0.651];
                app.const1Label.Text = 'RPM';
                app.Constant1EditField.Limits = [0.01 Inf];
                app.Constant1EditField.FontColor = [0 0.3882 0.651];
                app.Constant1EditField.Value = 1500;
                
                % Pitch
                app.const2Label.FontColor = [0 0.3882 0.651];
                app.const2Label.Text = 'Pitch (˚ )';
                app.Constant2EditField.Limits = [-90 90];
                app.Constant2EditField.FontColor = [0 0.3882 0.651];
                app.Constant2EditField.Value = 0;

                % V Start
                app.StartVmsLabel.FontColor = [0 0.3882 0.651];
                app.StartVmsLabel.Text = 'Start V (m/s)';
                app.StartVariableEditField.Limits = [0.01 Inf];
                app.StartVariableEditField.FontColor = [0 0.3882 0.651];
                app.StartVariableEditField.Value = 1;

                % V End
                app.EndVmsLabel.FontColor = [0 0.3882 0.651];
                app.EndVmsLabel.Text = 'End V (m/s)';
                app.EndVariableEditField.Limits = [0.01 Inf];
                app.EndVariableEditField.FontColor = [0 0.3882 0.651];
                app.EndVariableEditField.Value = 10;

                app.deltaEditField_2.Value = 1;
                
            elseif app.RPMButton.Value                                      % Variable = RPM

                % V
                app.const1Label.FontColor = [0.651 0.1961 0];
                app.const1Label.Text = 'V (m/s)';
                app.Constant1EditField.Limits = [0.01 Inf];
                app.Constant1EditField.FontColor = [0.651 0.1961 0];
                app.Constant1EditField.Value = 10;
                
                % Pitch
                app.const2Label.FontColor = [0.651 0.1961 0];
                app.const2Label.Text = 'Pitch (˚ )';
                app.Constant2EditField.Limits = [-90 90];
                app.Constant2EditField.FontColor = [0.651 0.1961 0];
                app.Constant2EditField.Value = 0;

                % RPM Start
                app.StartVmsLabel.FontColor = [0.651 0.1961 0];
                app.StartVmsLabel.Text = 'Start RPM';
                app.StartVariableEditField.Limits = [0.01 Inf];
                app.StartVariableEditField.FontColor = [0.651 0.1961 0];
                app.StartVariableEditField.Value = 100;

                % RPM End
                app.EndVmsLabel.FontColor = [0.651 0.1961 0];
                app.EndVmsLabel.Text = 'End RPM';
                app.EndVariableEditField.Limits = [0.01 Inf];
                app.EndVariableEditField.FontColor = [0.651 0.1961 0];
                app.EndVariableEditField.Value = 2000;
                
                app.deltaEditField_2.Value = 100;

            elseif app.PitchButton.Value                                    % Variable = Pitch

                % V
                app.const1Label.FontColor = [0.2824 0.502 0];
                app.const1Label.Text = 'V (m/s)';
                app.Constant1EditField.Limits = [0.01 Inf];
                app.Constant1EditField.FontColor = [0.2824 0.502 0];
                app.Constant1EditField.Value = 10;
                
                % RPM
                app.const2Label.FontColor = [0.2824 0.502 0];
                app.const2Label.Text = 'RPM';
                app.Constant2EditField.Limits = [0.01 Inf];
                app.Constant2EditField.FontColor = [0.2824 0.502 0];
                app.Constant2EditField.Value = 1500;

                % Pitch Start
                app.StartVmsLabel.FontColor = [0.2824 0.502 0];
                app.StartVmsLabel.Text = 'Start Pitch (˚ )';
                app.StartVariableEditField.Limits = [-90 90];
                app.StartVariableEditField.FontColor = [0.2824 0.502 0];
                app.StartVariableEditField.Value = 0;

                % Pitch End
                app.EndVmsLabel.FontColor = [0.2824 0.502 0];
                app.EndVmsLabel.Text = 'End Pitch (˚ )';
                app.EndVariableEditField.Limits = [-90 90];
                app.EndVariableEditField.FontColor = [0.2824 0.502 0];
                app.EndVariableEditField.Value = 10;

                app.deltaEditField_2.Value = 1;

            end
        end

        % Value changed function: PropellerDropDown
        function PropellerDropDownValueChanged(app, event)
            t5_plot(app);
        end

        % Button pushed function: AnalyzePropellerButton
        function AnalyzePropellerButtonPushed(app, event)
            wb = waitbar(0,'Preparing BEM analysis');                       % Progress bar
            current_prop = app.Propeller_Info(app.PropellerDropDown.Value);

            % Tab-4 propeller specifications
            Blade_data = cell2mat(current_prop.table(:,1:3));
            Blade_airfoil = current_prop.table(:,4);
            HubR = current_prop.hub_radius + Blade_data(1,1);
            PropR = current_prop.hub_radius + Blade_data(end,1);
            BladeN = current_prop.blade_number;
            BlockN = size(current_prop.table,1) - 1;

            % Tab-5 simulation parameters
            ElementN = app.ElementNumberEditField.Value;
            epsilon = app.EpsilonEditField.Value;
            relax = app.RelaxFactorEditField.Value;
            iter_limit = app.IterationLimitEditField_2.Value;
            rho = app.RhoEditField.Value;
            boolPLC = app.PrandtlLossCorrecrionCheckBox.Value;

            for k = 1:length(Blade_airfoil)
                if ~isKey(app.Airfoil_Info, Blade_airfoil{k}) || ~isfield(app.Airfoil_Info(Blade_airfoil{k}),'ext_res')
                    msgbox('\fontsize{12} There is no data of any airfoil.', 'Warning', 'warn', app.MsgboxSet)
                    close(wb);
                    return;
                end
            end

            %% Variable = V (Wind Speed)
            if app.VButton.Value
   
                RPM = app.Constant1EditField.Value;
                Pitch = app.Constant2EditField.Value;

                Vstart = app.StartVariableEditField.Value;
                Vend = app.EndVariableEditField.Value;
                Vdelta = app.deltaEditField_2.Value;
                WS = (Vstart:Vdelta:Vend)';
    
                Vlen = length(WS);
                Total_T = zeros(Vlen,1);
                Total_Q = zeros(Vlen,1);
                Total_P = zeros(Vlen,1);
                eta = zeros(Vlen,1);
    
                prog = linspace(0,1,Vlen+1);
                prog(1) = [];
                
                % BEM for each wind speed
                for n = 1:Vlen
                    waitbar(prog(n), wb,'BEM in progress...');
    
                    T = zeros(BlockN,1);
                    Q = zeros(BlockN,1);
                    P = zeros(BlockN,1);
                    
                    % Block Loop
                    for i = 1:BlockN
                        Block_data = Blade_data(i:i+1,:);
                        [alpha_data, cl1, cd1, cl2, cd2] = equalizer(app, i, Blade_airfoil);
                        
                        [T(i), Q(i), P(i)] = BEMFn(alpha_data, cl1, cl2, cd1, cd2, Block_data, HubR, PropR, BladeN, WS(n), RPM, Pitch, ElementN, epsilon, relax, iter_limit, rho, boolPLC);
                    end
    
                    Total_T(n) = max(sum(T), 0);
                    Total_Q(n) = max(sum(Q), 0);
                    Total_P(n) = max(sum(P), 0);
                    
                    % Calculate propeller efficiency
                    ct = Total_T(n)/(rho*(RPM/60)^2*(PropR*2)^4);
                    cp = Total_P(n)/(rho*(RPM/60)^3*(PropR*2)^5);
                    J = WS(n)/(RPM/60*PropR*2);
                    eta_temp = J*ct/cp;
                    if eta_temp < 0 || eta_temp == Inf || isnan(eta_temp)
                        eta(n) = 0;
                    elseif eta_temp > 1
                        eta(n) = 1;
                    else
                        eta(n) = eta_temp;
                    end
    
                end

                current_prop.bem_res = [WS Total_T Total_Q Total_P eta];
                current_prop.vari_mode = 1;
                app.Propeller_Info(app.PropellerDropDown.Value) = current_prop;

            %% Variable = RPM
            elseif app.RPMButton.Value

                WS = app.Constant1EditField.Value;
                Pitch = app.Constant2EditField.Value;

                RPMstart = app.StartVariableEditField.Value;
                RPMend = app.EndVariableEditField.Value;
                RPMdelta = app.deltaEditField_2.Value;
                RPM = (RPMstart:RPMdelta:RPMend)';
    
                RPMlen = length(RPM);
                Total_T = zeros(RPMlen,1);
                Total_Q = zeros(RPMlen,1);
                Total_P = zeros(RPMlen,1);
                eta = zeros(RPMlen,1);
    
                prog = linspace(0,1,RPMlen+1);
                prog(1) = [];
                
                % BEM for each RPM
                for n = 1:RPMlen
                    waitbar(prog(n), wb, 'BEM in progress...');
    
                    T = zeros(BlockN,1);
                    Q = zeros(BlockN,1);
                    P = zeros(BlockN,1);
                    
                    % Block Loop
                    for i = 1:BlockN
                        Block_data = Blade_data(i:i+1,:);
                        [alpha_data, cl1, cd1, cl2, cd2] = equalizer(app, i, Blade_airfoil);
                        
                        [T(i), Q(i), P(i)] = BEMFn(alpha_data, cl1, cl2, cd1, cd2, Block_data, HubR, PropR, BladeN, WS, RPM(n), Pitch, ElementN, epsilon, relax, iter_limit, rho, boolPLC);
                    end
    
                    Total_T(n) = max(sum(T), 0);
                    Total_Q(n) = max(sum(Q), 0);
                    Total_P(n) = max(sum(P), 0);
                    
                    % Calculate propeller efficiency
                    ct = Total_T(n)/(rho*(RPM(n)/60)^2*(PropR*2)^4);
                    cp = Total_P(n)/(rho*(RPM(n)/60)^3*(PropR*2)^5);
                    J = WS/(RPM(n)/60*PropR*2);
                    eta_temp = J*ct/cp;
                    if eta_temp < 0 || eta_temp == Inf || isnan(eta_temp)
                        eta(n) = 0;
                    elseif eta_temp > 1
                        eta(n) = 1;
                    else
                        eta(n) = eta_temp;
                    end
    
                end

                current_prop.bem_res = [RPM Total_T Total_Q Total_P eta];
                current_prop.vari_mode = 2;
                app.Propeller_Info(app.PropellerDropDown.Value) = current_prop;

            %% Variable = Pitch
            elseif app.PitchButton.Value

                WS = app.Constant1EditField.Value;
                RPM = app.Constant2EditField.Value;

                Pitchstart = app.StartVariableEditField.Value;
                Pitchend = app.EndVariableEditField.Value;
                Pitchdelta = app.deltaEditField_2.Value;
                Pitch = (Pitchstart:Pitchdelta:Pitchend)';
    
                Pitchlen = length(Pitch);
                Total_T = zeros(Pitchlen,1);
                Total_Q = zeros(Pitchlen,1);
                Total_P = zeros(Pitchlen,1);
                eta = zeros(Pitchlen,1);
    
                prog = linspace(0,1,Pitchlen+1);
                prog(1) = [];
                
                % BEM for each pitch
                for n = 1:Pitchlen
                    waitbar(prog(n), wb,'BEM in progress...');
    
                    T = zeros(BlockN,1);
                    Q = zeros(BlockN,1);
                    P = zeros(BlockN,1);
                    
                    % Block Loop
                    for i = 1:BlockN
                        Block_data = Blade_data(i:i+1,:);
                        [alpha_data, cl1, cd1, cl2, cd2] = equalizer(app, i, Blade_airfoil);
                        
                        [T(i), Q(i), P(i)] = BEMFn(alpha_data, cl1, cl2, cd1, cd2, Block_data, HubR, PropR, BladeN, WS, RPM, Pitch(n), ElementN, epsilon, relax, iter_limit, rho, boolPLC);
                    end

                    Total_T(n) = max(sum(T), 0);
                    Total_Q(n) = max(sum(Q), 0);
                    Total_P(n) = max(sum(P), 0);

                    % Calculate propeller efficiency
                    ct = Total_T(n)/(rho*(RPM/60)^2*(PropR*2)^4);
                    cp = Total_P(n)/(rho*(RPM/60)^3*(PropR*2)^5);
                    J = WS/(RPM/60*PropR*2);
                    eta_temp = J*ct/cp;
                    if eta_temp < 0 || eta_temp == Inf || isnan(eta_temp)
                        eta(n) = 0;
                    elseif eta_temp > 1
                        eta(n) = 1;
                    else
                        eta(n) = eta_temp;
                    end
    
                end
                
                current_prop.bem_res = [Pitch Total_T Total_Q Total_P eta];
                current_prop.vari_mode = 3;
                app.Propeller_Info(app.PropellerDropDown.Value) = current_prop;

            end

            t5_plot(app);
            close(wb);
            
        end

        % Button pushed function: ExportDataButton_2
        function ExportDataButton_2Pushed(app, event)
            cur_prop = app.Propeller_Info(app.PropellerDropDown.Value);
            if ~isfield(cur_prop, 'bem_res'); return; end
            
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);                                                    % Create a dummy figure so that uigetfile doesn't minimize our GUI (MATLAB 2022a version has bug)
            [filename, filepath] = uiputfile('*.txt', 'Write down file name', [app.PropellerDropDown.Value '_BEMresult.txt']);  % File export dialog box
            delete(f);                                                                                                          % Delete the dummy figure
            if filename == 0; return; end

            [~, name, ~] = fileparts(filename);
            writefilename = [filepath name '.txt'];

            if cur_prop.vari_mode == 1
                temp_data = ["Wind Speed  Trust      Torque         Power        Efficiency"; num2str(cur_prop.bem_res,8)];
            elseif cur_prop.vari_mode == 2
                temp_data = ["RPM     Trust          Torque         Power        Efficiency"; num2str(cur_prop.bem_res,8)];
            elseif cur_prop.vari_mode == 3
                temp_data = ["Pitch   Trust          Torque         Power        Efficiency"; num2str(cur_prop.bem_res,8)];
            end
            
            writematrix(temp_data, writefilename, 'Delimiter', '\t', 'QuoteStrings', 'none')
        end

        % Image clicked function: OpenImage
        function OpenImageClicked(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);            % Create a dummy figure so that uigetfile doesn't minimize our GUI (MATLAB 2022a version has bug)
            [filename, filepath] = uigetfile('*.mat', 'Select project file (.mat)');    % Open file selection dialog box, display .dat and .txt file
            delete(f);                                                                  % Delete the dummy figure
            if filename == 0; return; end

            openfilename = [filepath filename];
            temp = open(openfilename);
            
            app.Airfoil_Info = temp.airfoil_info;
            app.Propeller_Info = temp.propeller_info;
            app.UITable.Data = temp.t1_table;
            app.UITable2.Data = temp.t4_table;

            app.TabGroup.SelectedTab = app.AirfoilDesignTab;                % Move to tab-1
            t1_plot(app);
        end

        % Image clicked function: SaveImage
        function SaveImageClicked(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);                            % Create a dummy figure so that uigetfile doesn't minimize our GUI (MATLAB 2022a version has bug)
            [filename, filepath] = uiputfile('*.mat', 'Write down project file name', 'project.mat');   % File export dialog box
            delete(f);                                                                                  % Delete the dummy figure
            if filename == 0; return; end

            [~, name, ~] = fileparts(filename);
            writefilename = [filepath name '.mat'];
            
            airfoil_info = app.Airfoil_Info;
            propeller_info = app.Propeller_Info;
            t1_table = app.UITable.Data;
            t4_table = app.UITable2.Data;
            save(writefilename, "airfoil_info", "propeller_info", "t1_table", "t4_table");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'resources', 'title_img.png');
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AirfoilDesignTab = uitab(app.TabGroup);
            app.AirfoilDesignTab.Title = 'Airfoil Design';
            app.UIAxes1 = uiaxes(app.AirfoilDesignTab);
            xlabel(app.UIAxes1, 'X')
            ylabel(app.UIAxes1, 'Y')
            zlabel(app.UIAxes1, 'Z')
            app.UIAxes1.XLim = [-0.02 1.02];
            app.UIAxes1.YLim = [-0.32 0.32];
            app.UIAxes1.XAxisLocation = 'origin';
            app.UIAxes1.YAxisLocation = 'origin';
            app.UITable = uitable(app.AirfoilDesignTab);
            app.UITable.ColumnName = {'Airfoil Name'; 'Thickness (%)'; 'Camber (%)'; 'Points'};
            app.UITable.SelectionType = 'row';
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @UITableSelectionChanged, true);
            app.UITable.Tooltip = {''};
            app.Panel_1_2 = uipanel(app.AirfoilDesignTab);
            app.Panel_1_2.Enable = 'off';
            app.Panel_1_2.Title = 'NACA Airfoil Generator';
            app.Panel_1_2.Visible = 'off';
            app.GenerateButton = uibutton(app.Panel_1_2, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.NodesSpacingMethodButtonGroup = uibuttongroup(app.Panel_1_2);
            app.NodesSpacingMethodButtonGroup.Title = 'Nodes Spacing Method';
            app.UniformSpacingButton = uiradiobutton(app.NodesSpacingMethodButtonGroup);
            app.UniformSpacingButton.Text = 'Uniform-Spacing';
            app.HalfCosineButton = uiradiobutton(app.NodesSpacingMethodButtonGroup);
            app.HalfCosineButton.Text = 'Half-Cosine';
            app.HalfCosineButton.Value = true;
            app.CloseTEButtonGroup = uibuttongroup(app.Panel_1_2);
            app.CloseTEButtonGroup.Title = 'Close TE';
            app.NoButton = uiradiobutton(app.CloseTEButtonGroup);
            app.NoButton.Text = 'No';
            app.YesButton = uiradiobutton(app.CloseTEButtonGroup);
            app.YesButton.Text = 'Yes';
            app.YesButton.Value = true;
            app.digitsLabel = uilabel(app.Panel_1_2);
            app.digitsLabel.Text = 'NACA 4 or 5 Digits';
            app.Numberofpoints151Label = uilabel(app.Panel_1_2);
            app.Numberofpoints151Label.Text = 'Number of nodes (≤151)';
            app.NumberofnodesEditField = uieditfield(app.Panel_1_2, 'numeric');
            app.NumberofnodesEditField.Limits = [11 151];
            app.NumberofnodesEditField.RoundFractionalValues = 'on';
            app.NumberofnodesEditField.Value = 120;
            app.NACADigitsEditField = uieditfield(app.Panel_1_2, 'text');
            app.XButton = uibutton(app.Panel_1_2, 'push');
            app.XButton.ButtonPushedFcn = createCallbackFcn(app, @XButtonPushed, true);
            app.XButton.Text = '✖';
            app.Panel_1 = uipanel(app.AirfoilDesignTab);
            app.DeleteSelectedAirfoilButton = uibutton(app.Panel_1, 'push');
            app.DeleteSelectedAirfoilButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteSelectedAirfoilButtonPushed, true);
            app.DeleteSelectedAirfoilButton.Text = {'Delete'; 'Selected Airfoil'};
            app.ExportSelectedAirfoilButton = uibutton(app.Panel_1, 'push');
            app.ExportSelectedAirfoilButton.ButtonPushedFcn = createCallbackFcn(app, @ExportSelectedAirfoilButtonPushed, true);
            app.ExportSelectedAirfoilButton.Text = {'Export'; 'Selected Airfoil'};
            app.ManageAirfoilLabel = uilabel(app.Panel_1);
            app.ManageAirfoilLabel.Text = '· Manage Airfoil';
            app.NodesCheckBox = uicheckbox(app.Panel_1);
            app.NodesCheckBox.ValueChangedFcn = createCallbackFcn(app, @NodesCheckBoxValueChanged, true);
            app.NodesCheckBox.Text = 'Nodes';
            app.LineCheckBox = uicheckbox(app.Panel_1);
            app.LineCheckBox.ValueChangedFcn = createCallbackFcn(app, @LineCheckBoxValueChanged, true);
            app.LineCheckBox.Text = 'Line';
            app.LineCheckBox.Value = true;
            app.DisplayModeLabel = uilabel(app.Panel_1);
            app.DisplayModeLabel.Text = '· Display Mode';
            app.GenerateNACAAirfoilButton = uibutton(app.Panel_1, 'push');
            app.GenerateNACAAirfoilButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateNACAAirfoilButtonPushed, true);
            app.GenerateNACAAirfoilButton.Text = {'Generate'; 'NACA Airfoil'};
            app.ImportAirfoilButton = uibutton(app.Panel_1, 'push');
            app.ImportAirfoilButton.ButtonPushedFcn = createCallbackFcn(app, @ImportAirfoilButtonPushed, true);
            app.ImportAirfoilButton.Text = 'Import Airfoil';
            app.CreateAirfoilLabel = uilabel(app.Panel_1);
            app.CreateAirfoilLabel.Text = '· Create Airfoil';
            app.XfoilAnalysisTab = uitab(app.TabGroup);
            app.XfoilAnalysisTab.Title = 'Xfoil Analysis';
            app.XfoilAnalysisTab.ButtonDownFcn = createCallbackFcn(app, @XfoilAnalysisTabButtonDown, true);
            app.UIAxes2_4 = uiaxes(app.XfoilAnalysisTab);
            title(app.UIAxes2_4, '(CL/CD) vs alpha')
            xlabel(app.UIAxes2_4, '\alpha (˚ )')
            ylabel(app.UIAxes2_4, 'C_L/C_D')
            zlabel(app.UIAxes2_4, 'Z')
            app.UIAxes2_3 = uiaxes(app.XfoilAnalysisTab);
            title(app.UIAxes2_3, 'CL vs CD')
            xlabel(app.UIAxes2_3, 'C_D')
            ylabel(app.UIAxes2_3, 'C_L')
            zlabel(app.UIAxes2_3, 'Z')
            app.UIAxes2_2 = uiaxes(app.XfoilAnalysisTab);
            title(app.UIAxes2_2, 'CD vs alpha')
            xlabel(app.UIAxes2_2, '\alpha (˚ )')
            ylabel(app.UIAxes2_2, 'C_D')
            zlabel(app.UIAxes2_2, 'Z')
            app.UIAxes2_1 = uiaxes(app.XfoilAnalysisTab);
            title(app.UIAxes2_1, 'CL vs alpha')
            xlabel(app.UIAxes2_1, '\alpha (˚ )')
            ylabel(app.UIAxes2_1, 'C_L')
            zlabel(app.UIAxes2_1, 'Z')
            app.Panel_2 = uipanel(app.XfoilAnalysisTab);
            app.AirfoilDropDownLabel = uilabel(app.Panel_2);
            app.AirfoilDropDownLabel.Text = 'Airfoil';
            app.ReynoldsNumberEditFieldLabel = uilabel(app.Panel_2);
            app.ReynoldsNumberEditFieldLabel.Text = 'Reynolds Number';
            app.MachEditFieldLabel = uilabel(app.Panel_2);
            app.MachEditFieldLabel.Text = 'Mach';
            app.NCritEditFieldLabel = uilabel(app.Panel_2);
            app.NCritEditFieldLabel.Text = 'NCrit';
            app.TripLocationtopEditFieldLabel = uilabel(app.Panel_2);
            app.TripLocationtopEditFieldLabel.Text = 'TripLocation (top)';
            app.TripLocationbotEditFieldLabel = uilabel(app.Panel_2);
            app.TripLocationbotEditFieldLabel.Text = 'TripLocation (bot)';
            app.StartAlphaLabel = uilabel(app.Panel_2);
            app.StartAlphaLabel.Text = 'Start Alpha (˚ )';
            app.ExportDataButton = uibutton(app.Panel_2, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportDataButton.Text = 'Export Data';
            app.AnalyzeAirfoilButton = uibutton(app.Panel_2, 'push');
            app.AnalyzeAirfoilButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeAirfoilButtonPushed, true);
            app.AnalyzeAirfoilButton.Text = 'Analyze Airfoil';
            app.ViscousCheckBox = uicheckbox(app.Panel_2);
            app.ViscousCheckBox.Text = ' Viscous';
            app.ViscousCheckBox.Value = true;
            app.deltaEditField = uieditfield(app.Panel_2, 'numeric');
            app.deltaEditField.Value = 1;
            app.EndAlphaEditField = uieditfield(app.Panel_2, 'numeric');
            app.EndAlphaEditField.Limits = [-180 180];
            app.EndAlphaEditField.Value = 15;
            app.StartAlphaEditField = uieditfield(app.Panel_2, 'numeric');
            app.StartAlphaEditField.Limits = [-180 180];
            app.EndAlphaLabel = uilabel(app.Panel_2);
            app.EndAlphaLabel.Text = 'End Alpha (˚ )';
            app.deltaEditField_3Label = uilabel(app.Panel_2);
            app.deltaEditField_3Label.Text = 'delta';
            app.SequenceCheckBox = uicheckbox(app.Panel_2);
            app.SequenceCheckBox.ValueChangedFcn = createCallbackFcn(app, @SequenceCheckBoxValueChanged, true);
            app.SequenceCheckBox.Text = ' Sequence';
            app.SequenceCheckBox.Value = true;
            app.AnalysisSettingsLabel = uilabel(app.Panel_2);
            app.AnalysisSettingsLabel.Text = '· Analysis Settings';
            app.IterationLimitEditFieldLabel = uilabel(app.Panel_2);
            app.IterationLimitEditFieldLabel.Text = 'Iteration Limit';
            app.IterationLimitEditField = uieditfield(app.Panel_2, 'numeric');
            app.IterationLimitEditField.Limits = [1 Inf];
            app.IterationLimitEditField.RoundFractionalValues = 'on';
            app.IterationLimitEditField.ValueDisplayFormat = '%.0f';
            app.IterationLimitEditField.Value = 100;
            app.TripLocationbotEditField = uieditfield(app.Panel_2, 'numeric');
            app.TripLocationbotEditField.Limits = [0 1];
            app.TripLocationbotEditField.ValueDisplayFormat = '%.2f';
            app.TripLocationbotEditField.Value = 1;
            app.TripLocationtopEditField = uieditfield(app.Panel_2, 'numeric');
            app.TripLocationtopEditField.Limits = [0 1];
            app.TripLocationtopEditField.ValueDisplayFormat = '%.2f';
            app.TripLocationtopEditField.Value = 1;
            app.NCritEditField = uieditfield(app.Panel_2, 'numeric');
            app.NCritEditField.Limits = [0.01 Inf];
            app.NCritEditField.ValueDisplayFormat = '%.2f';
            app.NCritEditField.Value = 9;
            app.MachEditField = uieditfield(app.Panel_2, 'numeric');
            app.MachEditField.Limits = [0 Inf];
            app.MachEditField.ValueDisplayFormat = '%.2f';
            app.ReynoldsNumberEditField = uieditfield(app.Panel_2, 'numeric');
            app.ReynoldsNumberEditField.RoundFractionalValues = 'on';
            app.ReynoldsNumberEditField.Value = 300000;
            app.DefineAnalysisLabel = uilabel(app.Panel_2);
            app.DefineAnalysisLabel.Text = '· Define Analysis';
            app.AirfoilDropDown_2 = uidropdown(app.Panel_2);
            app.AirfoilDropDown_2.Items = {'* empty'};
            app.AirfoilDropDown_2.ValueChangedFcn = createCallbackFcn(app, @AirfoilDropDown_2ValueChanged, true);
            app.AirfoilDropDown_2.Value = '* empty';
            app.SelectAirfoilLabel = uilabel(app.Panel_2);
            app.SelectAirfoilLabel.Text = '· Select Airfoil';
            app.PolarExtrapolationTab = uitab(app.TabGroup);
            app.PolarExtrapolationTab.Title = 'Polar Extrapolation';
            app.PolarExtrapolationTab.ButtonDownFcn = createCallbackFcn(app, @PolarExtrapolationTabButtonDown, true);
            app.UIAxes3_2 = uiaxes(app.PolarExtrapolationTab);
            title(app.UIAxes3_2, 'CD vs alpha')
            xlabel(app.UIAxes3_2, '\alpha')
            ylabel(app.UIAxes3_2, 'C_D')
            zlabel(app.UIAxes3_2, 'Z')
            app.UIAxes3_2.XLim = [-180 180];
            app.UIAxes3_2.XTick = [-180 -120 -60 0 60 120 180];
            app.UIAxes3_1 = uiaxes(app.PolarExtrapolationTab);
            title(app.UIAxes3_1, 'CL vs alpha')
            xlabel(app.UIAxes3_1, '\alpha')
            ylabel(app.UIAxes3_1, 'C_L')
            zlabel(app.UIAxes3_1, 'Z')
            app.UIAxes3_1.XLim = [-180 180];
            app.UIAxes3_1.XTick = [-180 -120 -60 0 60 120 180];
            app.Panel_3 = uipanel(app.PolarExtrapolationTab);
            app.ASliderLabel = uilabel(app.Panel_3);
            app.ASliderLabel.Text = 'A+';
            app.BSliderLabel = uilabel(app.Panel_3);
            app.BSliderLabel.Text = 'B+';
            app.ASlider_2Label = uilabel(app.Panel_3);
            app.ASlider_2Label.Text = 'A-';
            app.BSlider_2Label = uilabel(app.Panel_3);
            app.BSlider_2Label.Text = 'B-';
            app.SaveButton = uibutton(app.Panel_3, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.BmSlider = uislider(app.Panel_3);
            app.BmSlider.Limits = [0 40];
            app.BmSlider.MajorTicks = [0 5 10 15 20 25 30 35 40];
            app.BmSlider.MajorTickLabels = {''};
            app.BmSlider.ValueChangingFcn = createCallbackFcn(app, @BmSliderValueChanging, true);
            app.BmSlider.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40];
            app.BmSlider.Value = 5;
            app.AmSlider = uislider(app.Panel_3);
            app.AmSlider.Limits = [0 40];
            app.AmSlider.MajorTicks = [0 5 10 15 20 25 30 35 40];
            app.AmSlider.MajorTickLabels = {''};
            app.AmSlider.ValueChangingFcn = createCallbackFcn(app, @AmSliderValueChanging, true);
            app.AmSlider.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40];
            app.AmSlider.Value = 15;
            app.BpSlider = uislider(app.Panel_3);
            app.BpSlider.Limits = [0 40];
            app.BpSlider.MajorTicks = [0 5 10 15 20 25 30 35 40];
            app.BpSlider.MajorTickLabels = {''};
            app.BpSlider.ValueChangingFcn = createCallbackFcn(app, @BpSliderValueChanging, true);
            app.BpSlider.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40];
            app.BpSlider.Value = 10;
            app.ApSlider = uislider(app.Panel_3);
            app.ApSlider.Limits = [0 40];
            app.ApSlider.MajorTicks = [0 5 10 15 20 25 30 35 40];
            app.ApSlider.MajorTickLabels = {''};
            app.ApSlider.ValueChangingFcn = createCallbackFcn(app, @ApSliderValueChanging, true);
            app.ApSlider.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40];
            app.ApSlider.Value = 20;
            app.Create360PolarLabel = uilabel(app.Panel_3);
            app.Create360PolarLabel.Text = '· Create 360 Polar';
            app.AirfoilDropDown_2Label = uilabel(app.Panel_3);
            app.AirfoilDropDown_2Label.Text = 'Airfoil';
            app.AirfoilDropDown_3 = uidropdown(app.Panel_3);
            app.AirfoilDropDown_3.Items = {'* empty'};
            app.AirfoilDropDown_3.ValueChangedFcn = createCallbackFcn(app, @AirfoilDropDown_3ValueChanged, true);
            app.AirfoilDropDown_3.Value = '* empty';
            app.SelectAirfoilLabel_2 = uilabel(app.Panel_3);
            app.SelectAirfoilLabel_2.Text = '· Select Airfoil';
            app.PropellerDesignTab = uitab(app.TabGroup);
            app.PropellerDesignTab.Title = 'Propeller Design';
            app.PropellerDesignTab.ButtonDownFcn = createCallbackFcn(app, @PropellerDesignTabButtonDown, true);
            app.UIAxes4 = uiaxes(app.PropellerDesignTab);
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes4.XLimitMethod = 'tight';
            app.UIAxes4.YLimitMethod = 'tight';
            app.UIAxes4.ZLimitMethod = 'tight';
            app.UIAxes4.XAxisLocation = 'origin';
            app.UIAxes4.YAxisLocation = 'origin';
            app.Panel_4 = uipanel(app.PropellerDesignTab);
            app.BladeNumberEditFieldLabel = uilabel(app.Panel_4);
            app.BladeNumberEditFieldLabel.Text = 'Blade Number';
            app.SavePropellerButton = uibutton(app.Panel_4, 'push');
            app.SavePropellerButton.ButtonPushedFcn = createCallbackFcn(app, @SavePropellerButtonPushed, true);
            app.SavePropellerButton.Text = 'Save Propeller';
            app.PropellernameTextArea = uitextarea(app.Panel_4);
            app.PropellernameTextArea.Value = {'Propeller 1'};
            app.BladeNumberEditField = uieditfield(app.Panel_4, 'numeric');
            app.BladeNumberEditField.Limits = [1 Inf];
            app.BladeNumberEditField.RoundFractionalValues = 'on';
            app.BladeNumberEditField.ValueChangedFcn = createCallbackFcn(app, @BladeNumberEditFieldValueChanged, true);
            app.BladeNumberEditField.Value = 2;
            app.HubRadiusmEditFieldLabel = uilabel(app.Panel_4);
            app.HubRadiusmEditFieldLabel.Text = 'Hub Radius (m)';
            app.HubRadiusEditField = uieditfield(app.Panel_4, 'numeric');
            app.HubRadiusEditField.Limits = [0.01 Inf];
            app.HubRadiusEditField.ValueChangedFcn = createCallbackFcn(app, @HubRadiusEditFieldValueChanged, true);
            app.HubRadiusEditField.Value = 0.2;
            app.AirfoilDropDown_3Label = uilabel(app.Panel_4);
            app.AirfoilDropDown_3Label.Text = 'Airfoil';
            app.PosmEditFieldLabel = uilabel(app.Panel_4);
            app.PosmEditFieldLabel.Text = 'Pos (m)';
            app.TwistLabel = uilabel(app.Panel_4);
            app.TwistLabel.Text = 'Twist (˚ )';
            app.ChordmEditFieldLabel = uilabel(app.Panel_4);
            app.ChordmEditFieldLabel.Text = 'Chord (m)';
            app.PropellernameTextAreaLabel = uilabel(app.Panel_4);
            app.PropellernameTextAreaLabel.Text = 'Propeller name';
            app.DeleteSectionButton = uibutton(app.Panel_4, 'push');
            app.DeleteSectionButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteSectionButtonPushed, true);
            app.DeleteSectionButton.Text = 'Delete Section';
            app.UITable2 = uitable(app.Panel_4);
            app.UITable2.ColumnName = {'Position (m)'; 'Chord (m)'; 'Twist (˚ )'; 'Airfoil name'};
            app.UITable2.SelectionType = 'row';
            app.UITable2.SelectionChangedFcn = createCallbackFcn(app, @UITable2SelectionChanged, true);
            app.InsertSectionButton = uibutton(app.Panel_4, 'push');
            app.InsertSectionButton.ButtonPushedFcn = createCallbackFcn(app, @InsertSectionButtonPushed, true);
            app.InsertSectionButton.Text = 'Insert Section';
            app.AirfoilDropDown_4 = uidropdown(app.Panel_4);
            app.AirfoilDropDown_4.Items = {'* empty'};
            app.AirfoilDropDown_4.Value = '* empty';
            app.TwistEditField = uieditfield(app.Panel_4, 'numeric');
            app.TwistEditField.Limits = [-90 90];
            app.ChordEditField = uieditfield(app.Panel_4, 'numeric');
            app.ChordEditField.Limits = [0.001 Inf];
            app.ChordEditField.Value = 0.1;
            app.PosEditField = uieditfield(app.Panel_4, 'numeric');
            app.PosEditField.Limits = [0 Inf];
            app.PropellerDesignLabel = uilabel(app.Panel_4);
            app.PropellerDesignLabel.Text = '· Propeller Design';
            app.PerspectiveCheckBox = uicheckbox(app.Panel_4);
            app.PerspectiveCheckBox.ValueChangedFcn = createCallbackFcn(app, @PerspectiveCheckBoxValueChanged, true);
            app.PerspectiveCheckBox.Text = 'Perspective';
            app.PropellerCheckBox = uicheckbox(app.Panel_4);
            app.PropellerCheckBox.ValueChangedFcn = createCallbackFcn(app, @PropellerCheckBoxValueChanged, true);
            app.PropellerCheckBox.Text = 'Propeller';
            app.WireframeCheckBox = uicheckbox(app.Panel_4);
            app.WireframeCheckBox.ValueChangedFcn = createCallbackFcn(app, @WireframeCheckBoxValueChanged, true);
            app.WireframeCheckBox.Text = 'Wireframe';
            app.WireframeCheckBox.Value = true;
            app.CenteryaxisCheckBox = uicheckbox(app.Panel_4);
            app.CenteryaxisCheckBox.ValueChangedFcn = createCallbackFcn(app, @CenteryaxisCheckBoxValueChanged, true);
            app.CenteryaxisCheckBox.Text = 'Center & y-axis';
            app.CenteryaxisCheckBox.Value = true;
            app.DisplayControlLabel = uilabel(app.Panel_4);
            app.DisplayControlLabel.Text = '· Display Control';
            app.isometric2Button = uibutton(app.PropellerDesignTab, 'push');
            app.isometric2Button.ButtonPushedFcn = createCallbackFcn(app, @isometric2ButtonPushed, true);
            app.isometric2Button.Text = 'isometric 2';
            app.isometric1Button = uibutton(app.PropellerDesignTab, 'push');
            app.isometric1Button.ButtonPushedFcn = createCallbackFcn(app, @isometric1ButtonPushed, true);
            app.isometric1Button.Text = 'isometric 1';
            app.yzaxisButton = uibutton(app.PropellerDesignTab, 'push');
            app.yzaxisButton.ButtonPushedFcn = createCallbackFcn(app, @yzaxisButtonPushed, true);
            app.yzaxisButton.Text = 'y-z axis';
            app.xyaxisButton = uibutton(app.PropellerDesignTab, 'push');
            app.xyaxisButton.ButtonPushedFcn = createCallbackFcn(app, @xyaxisButtonPushed, true);
            app.xyaxisButton.Text = 'x-y axis';
            app.xzaxisButton = uibutton(app.PropellerDesignTab, 'push');
            app.xzaxisButton.ButtonPushedFcn = createCallbackFcn(app, @xzaxisButtonPushed, true);
            app.xzaxisButton.Text = 'x-z axis';
            app.ViewingControlLabel = uilabel(app.PropellerDesignTab);
            app.ViewingControlLabel.Text = '· Viewing Control';
            app.BEMAnalysisTab = uitab(app.TabGroup);
            app.BEMAnalysisTab.Title = 'BEM Analysis';
            app.BEMAnalysisTab.ButtonDownFcn = createCallbackFcn(app, @BEMAnalysisTabButtonDown, true);
            app.UIAxes5_4 = uiaxes(app.BEMAnalysisTab);
            title(app.UIAxes5_4, 'Propeller Efficiency')
            ylabel(app.UIAxes5_4, 'η')
            zlabel(app.UIAxes5_4, 'Z')
            app.UIAxes5_4.YLim = [0 1];
            app.UIAxes5_4.Tag = 'a4';
            app.UIAxes5_3 = uiaxes(app.BEMAnalysisTab);
            title(app.UIAxes5_3, 'Power')
            ylabel(app.UIAxes5_3, 'P (W)')
            zlabel(app.UIAxes5_3, 'Z')
            app.UIAxes5_3.Tag = 'a4';
            app.UIAxes5_2 = uiaxes(app.BEMAnalysisTab);
            title(app.UIAxes5_2, 'Torque')
            ylabel(app.UIAxes5_2, 'Q (Nm)')
            zlabel(app.UIAxes5_2, 'Z')
            app.UIAxes5_2.Tag = 'a4';
            app.UIAxes5_1 = uiaxes(app.BEMAnalysisTab);
            title(app.UIAxes5_1, 'Thrust')
            ylabel(app.UIAxes5_1, 'T (N)')
            zlabel(app.UIAxes5_1, 'Z')
            app.UIAxes5_1.Tag = 'a4';
            app.Panel_5 = uipanel(app.BEMAnalysisTab);
            app.const2Label = uilabel(app.Panel_5);
            app.const2Label.Text = 'const 2';
            app.RhoEditFieldLabel = uilabel(app.Panel_5);
            app.RhoEditFieldLabel.Text = 'Rho';
            app.ViscosityEditFieldLabel = uilabel(app.Panel_5);
            app.ViscosityEditFieldLabel.Text = 'Viscosity';
            app.IterationLimitEditField_2Label = uilabel(app.Panel_5);
            app.IterationLimitEditField_2Label.Text = 'Iteration Limit';
            app.EpsilonEditFieldLabel = uilabel(app.Panel_5);
            app.EpsilonEditFieldLabel.Text = 'Epsilon';
            app.RelaxFactorEditFieldLabel = uilabel(app.Panel_5);
            app.RelaxFactorEditFieldLabel.Text = 'Relax Factor';
            app.StartVmsLabel = uilabel(app.Panel_5);
            app.StartVmsLabel.Text = 'start vari';
            app.ExportDataButton_2 = uibutton(app.Panel_5, 'push');
            app.ExportDataButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButton_2Pushed, true);
            app.ExportDataButton_2.Text = 'Export Data';
            app.AnalyzePropellerButton = uibutton(app.Panel_5, 'push');
            app.AnalyzePropellerButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzePropellerButtonPushed, true);
            app.AnalyzePropellerButton.Text = 'Analyze Propeller';
            app.EndVmsLabel = uilabel(app.Panel_5);
            app.EndVmsLabel.Text = 'end vari';
            app.deltaEditField_2Label = uilabel(app.Panel_5);
            app.deltaEditField_2Label.Text = 'delta';
            app.deltaEditField_2 = uieditfield(app.Panel_5, 'numeric');
            app.deltaEditField_2.Limits = [0.01 Inf];
            app.deltaEditField_2.Value = 1;
            app.EndVariableEditField = uieditfield(app.Panel_5, 'numeric');
            app.EndVariableEditField.Limits = [0.01 Inf];
            app.EndVariableEditField.Value = 10;
            app.StartVariableEditField = uieditfield(app.Panel_5, 'numeric');
            app.StartVariableEditField.Limits = [0.01 Inf];
            app.StartVariableEditField.Value = 1;
            app.AnalysisSettingsLabel_2 = uilabel(app.Panel_5);
            app.AnalysisSettingsLabel_2.Text = '· Analysis Settings';
            app.PrandtlLossCorrecrionCheckBox = uicheckbox(app.Panel_5);
            app.PrandtlLossCorrecrionCheckBox.Text = ' Prandtl Loss Correcrion';
            app.ViscosityEditField = uieditfield(app.Panel_5, 'numeric');
            app.ViscosityEditField.Limits = [0 Inf];
            app.ViscosityEditField.Value = 1.78e-05;
            app.RhoEditField = uieditfield(app.Panel_5, 'numeric');
            app.RhoEditField.Limits = [0 Inf];
            app.RhoEditField.Value = 1.225;
            app.IterationLimitEditField_2 = uieditfield(app.Panel_5, 'numeric');
            app.IterationLimitEditField_2.Limits = [1 Inf];
            app.IterationLimitEditField_2.RoundFractionalValues = 'on';
            app.IterationLimitEditField_2.Value = 2000;
            app.RelaxFactorEditField = uieditfield(app.Panel_5, 'numeric');
            app.RelaxFactorEditField.Limits = [0.001 1];
            app.RelaxFactorEditField.Value = 0.1;
            app.EpsilonEditField = uieditfield(app.Panel_5, 'numeric');
            app.EpsilonEditField.Limits = [1e-06 Inf];
            app.EpsilonEditField.Value = 0.001;
            app.PropellerDropDownLabel = uilabel(app.Panel_5);
            app.PropellerDropDownLabel.Text = 'Propeller';
            app.ElementNumberEditField = uieditfield(app.Panel_5, 'numeric');
            app.ElementNumberEditField.Limits = [1 Inf];
            app.ElementNumberEditField.RoundFractionalValues = 'on';
            app.ElementNumberEditField.Value = 20;
            app.SimulationParametersLabel = uilabel(app.Panel_5);
            app.SimulationParametersLabel.Text = '· Simulation Parameters';
            app.Constant2EditField = uieditfield(app.Panel_5, 'numeric');
            app.Constant2EditField.Limits = [-90 90];
            app.const1Label = uilabel(app.Panel_5);
            app.const1Label.Text = 'const 1';
            app.Constant1EditField = uieditfield(app.Panel_5, 'numeric');
            app.Constant1EditField.Limits = [0.01 Inf];
            app.Constant1EditField.Value = 1500;
            app.PropellerDataLabel = uilabel(app.Panel_5);
            app.PropellerDataLabel.Text = '· Propeller Data';
            app.PropellerDropDown = uidropdown(app.Panel_5);
            app.PropellerDropDown.Items = {'* empty'};
            app.PropellerDropDown.ValueChangedFcn = createCallbackFcn(app, @PropellerDropDownValueChanged, true);
            app.PropellerDropDown.Value = '* empty';
            app.SelectPropellerLabel = uilabel(app.Panel_5);
            app.SelectPropellerLabel.Text = '· Select Propeller';
            app.ElementNumberEditFieldLabel = uilabel(app.Panel_5);
            app.ElementNumberEditFieldLabel.Text = 'Element Number';
            app.ButtonGroup = uibuttongroup(app.Panel_5);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.PitchButton = uiradiobutton(app.ButtonGroup);
            app.PitchButton.Text = 'Pitch';
            app.RPMButton = uiradiobutton(app.ButtonGroup);
            app.RPMButton.Text = 'RPM';
            app.VButton = uiradiobutton(app.ButtonGroup);
            app.VButton.Text = 'V';
            app.VButton.Value = true;
            app.VariablexaxisLabel = uilabel(app.ButtonGroup);
            app.VariablexaxisLabel.Text = '· Variable (x-axis)';
            app.OpenImage = uiimage(app.UIFigure);
            app.OpenImage.ImageClickedFcn = createCallbackFcn(app, @OpenImageClicked, true);
            app.OpenImage.ImageSource = fullfile(pathToMLAPP, 'resources', 'open_img.png');
            app.SaveImage = uiimage(app.UIFigure);
            app.SaveImage.ImageClickedFcn = createCallbackFcn(app, @SaveImageClicked, true);
            app.SaveImage.ImageSource = fullfile(pathToMLAPP, 'resources', 'save_img.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CNUPropeller

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end